classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ButtonReference           matlab.ui.control.Button
        ButtonExportXY            matlab.ui.control.Button
        ButtonTrackFilaments      matlab.ui.control.Button
        GotoLabel_16              matlab.ui.control.Label
        EditHorzTrackPosition     matlab.ui.control.NumericEditField
        GotoLabel_15              matlab.ui.control.Label
        EditVertTrackPosition     matlab.ui.control.NumericEditField
        GotoLabel_14              matlab.ui.control.Label
        ButtonCrop                matlab.ui.control.Button
        ButtonStrip               matlab.ui.control.Button
        CheckBoxShowTracked       matlab.ui.control.CheckBox
        EditReferenceMillimeters  matlab.ui.control.NumericEditField
        GotoLabel_18              matlab.ui.control.Label
        EditReferencePixels       matlab.ui.control.NumericEditField
        EditFilamentDiameter      matlab.ui.control.NumericEditField
        GotoLabel_11              matlab.ui.control.Label
        EditBrushWidth            matlab.ui.control.NumericEditField
        GotoLabel_10              matlab.ui.control.Label
        EditBrushBodyDiameter     matlab.ui.control.NumericEditField
        GotoLabel_17              matlab.ui.control.Label
        EditBrushDiameter         matlab.ui.control.NumericEditField
        Status                    matlab.ui.control.EditField
        GotoLabel_9               matlab.ui.control.Label
        RadioGroupDisplayMode     matlab.ui.container.ButtonGroup
        RadioBinary               matlab.ui.control.RadioButton
        RadioGray                 matlab.ui.control.RadioButton
        RadioColor                matlab.ui.control.RadioButton
        EditFrameStep             matlab.ui.control.NumericEditField
        GotoLabel_8               matlab.ui.control.Label
        GotoLabel_7               matlab.ui.control.Label
        ButtonLoad                matlab.ui.control.Button
        ButtonSave                matlab.ui.control.Button
        EditCropLower             matlab.ui.control.NumericEditField
        GotoLabel_5               matlab.ui.control.Label
        EditCropUpper             matlab.ui.control.NumericEditField
        GotoLabel_4               matlab.ui.control.Label
        EditCropRight             matlab.ui.control.NumericEditField
        GotoLabel_3               matlab.ui.control.Label
        EditCropLeft              matlab.ui.control.NumericEditField
        GotoLabel_2               matlab.ui.control.Label
        ButtonSetLast             matlab.ui.control.Button
        ButtonSetFirst            matlab.ui.control.Button
        ButtonPause               matlab.ui.control.Button
        ButtonPlay                matlab.ui.control.Button
        EditCurrentFrame          matlab.ui.control.NumericEditField
        GotoLabel                 matlab.ui.control.Label
        ButtonFrameBackward       matlab.ui.control.Button
        ButtonFrameForward        matlab.ui.control.Button
        SliderCurrentFrame        matlab.ui.control.Slider
        EditTotalFramesRatio      matlab.ui.control.NumericEditField
        CheckBoxPreCrop           matlab.ui.control.CheckBox
        ButtonImportVideo         matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)

        inPath = 'C:\Data\FilamentCounting'
        outPath = 'UserData'
        videoFileName
        %stream
        height
        width
        cropRect
        millimetersPerPixel
        
        currentFrame = 1
        numFrames = 1
        frameStep = 1        
        firstFrame = 1
        lastFrame = Inf
        playing = false

        erodeRadius = 4
        dilateRadius = 1
        
        imageRGB
        imageGray
        imageBin
        houghCenters
        
    end
    
    methods (Access = private)

        % Set coordinate unit conversion factor
        function SetReferenceLengths(app)
            app.millimetersPerPixel = app.EditReferenceMillimeters. Value / app.EditReferencePixels.Value;
        end

        % Convert pixels to millimeters
        function mm = px2mm(app, px)
            mm = px * app.millimetersPerPixel;
        end

        % Convert millimeters to pixels
        function px = mm2px(app, mm, rounding)
            if rounding
                px = round(mm / app.millimetersPerPixel);
            else
                px = mm / app.millimetersPerPixel;
            end
        end


%         % Clamp value to interval a and b
%         function val = Clamp(~, val, a, b)
%             if val < a
%                 val = 1;
%             elseif val > b
%                 val = b;
%             end
%         end

        function DisableAllElements(app)
            app.playing = false;
            app.ButtonImportVideo.Enable = "off";
            app.SliderCurrentFrame.Enable = "off";
            app.ButtonFrameForward.Enable = "off";
            app.ButtonFrameBackward.Enable = "off";
            app.EditCurrentFrame.Enable = "off";
            app.ButtonPlay.Enable = "off";
            app.ButtonPause.Enable = "off";
            app.ButtonSetFirst.Enable = "off";
            app.ButtonSetLast.Enable = "off";
            app.CheckBoxPreCrop.Enable = "off";
            app.EditCropLeft.Enable = "off";
            app.EditCropRight.Enable = "off";
            app.EditCropUpper.Enable = "off";
            app.EditCropLower.Enable = "off";
            app.ButtonSave.Enable = "off";            
            %app.EditTrackWindowHeight.Enable = "off";            
            app.EditFrameStep.Enable = "off";
            app.RadioGroupDisplayMode.Enable = "off";
            app.RadioBinary.Enable = "off";
            app.RadioGray.Enable = "off";
            app.RadioColor.Enable = "off";
            app.ButtonStrip.Enable = "off";
            app.ButtonCrop.Enable = "off";
            app.EditVertTrackPosition.Enable = "off";
            app.EditHorzTrackPosition.Enable = "off";
            app.ButtonReference.Enable = "off";
            %app.EditReferenceMillimeters.Enable = "off";
            %app.EditReferencePixels.Enable = "off";
            app.ButtonTrackFilaments.Enable = "off";
            app.ButtonExportXY.Enable = "off";
        end

        function EnableAfterImport(app)
            app.ButtonImportVideo.Enable = "on";
            app.SliderCurrentFrame.Enable = "on";
            app.ButtonFrameForward.Enable = "on";
            app.ButtonFrameBackward.Enable = "on";
            app.EditCurrentFrame.Enable = "on";
            app.ButtonPlay.Enable = "on";
            app.ButtonPause.Enable = "on";
            app.ButtonSetFirst.Enable = "on";
            app.ButtonSetLast.Enable = "on";
            app.CheckBoxPreCrop.Enable = "on";
            app.EditCropLeft.Enable = "on";
            app.EditCropRight.Enable = "on";
            app.EditCropUpper.Enable = "on";
            app.EditCropLower.Enable = "on";
            %app.ButtonSave.Enable = "on";            
            %app.EditTrackWindowHeight.Enable = "on";            
            app.EditFrameStep.Enable = "on";
            app.RadioGroupDisplayMode.Enable = "on";
            app.RadioBinary.Enable = "on";
            app.RadioGray.Enable = "on";
            app.RadioColor.Enable = "on";
            app.ButtonStrip.Enable = "on";
            app.ButtonCrop.Enable = "on";
            app.EditVertTrackPosition.Enable = "on";
            app.EditHorzTrackPosition.Enable = "on";
            app.ButtonReference.Enable = "on";
            %app.EditReferenceMillimeters.Enable = "on";
            %app.EditReferencePixels.Enable = "on";
            app.ButtonTrackFilaments.Enable = "on";
        end

        % Go to frame at index, but clamp first
        function GotoFrame(app, index)
            app.currentFrame = Clamp(index, 1, app.numFrames);
            app.EditCurrentFrame.Value = app.currentFrame;
            app.SliderCurrentFrame.Value = app.currentFrame;
            Refresh(app);
        end
        
        % Update current image in UIAxes
        function Refresh(app)
            if isempty(app.imageRGB)  % no image initialized yet
                return
            end
            if app.RadioColor.Value  % show RGB image
                image = app.imageRGB{app.currentFrame};
            elseif app.RadioGray.Value  % show grayscale image
                image = app.imageGray{app.currentFrame};      
            elseif app.RadioBinary.Value  % show binarized image
                image = 255 * uint8(app.imageBin{app.currentFrame});
            end
            if app.CheckBoxShowTracked.Value && ~isempty(app.houghCenters)  % overlay tracked filaments
                image = insertMarker(image, app.houghCenters{app.currentFrame}, '+', 'Color', 'red');
            end
            imshow(image, 'Parent', app.UIAxes);
            hold(app.UIAxes, "on")

            ShowSupportLines(app);
            
            hold(app.UIAxes, "off")
            drawnow;
        end

        
        % Draw lines that mark the cropping and tracking areas
        function ShowSupportLines(app)
            % Cropping area
            plot(app.UIAxes, [1 app.width], [app.EditCropUpper.Value app.EditCropUpper.Value],'Color','g','LineWidth',1);
            plot(app.UIAxes, [1 app.width], [app.EditCropLower.Value app.EditCropLower.Value],'Color','g','LineWidth',1);
            plot(app.UIAxes, [app.EditCropLeft.Value app.EditCropLeft.Value], [1 app.height] ,'Color','g','LineWidth',1);
            plot(app.UIAxes, [app.EditCropRight.Value app.EditCropRight.Value], [1 app.height] ,'Color','g','LineWidth',1);

            % Tracking area
            plot(app.UIAxes, [1 app.width], [app.EditVertTrackPosition.Value app.EditVertTrackPosition.Value],'Color','b','LineWidth',1);
            plot(app.UIAxes, [app.EditHorzTrackPosition.Value app.EditHorzTrackPosition.Value], [1 app.height] ,'Color','b','LineWidth',1);
            
        end

        % Set height and width of images
        function SetFrameSize(app, height, width)            
            app.height = height;
            app.width = width;
        end

        function SetNumFrames(app, index)
            app.numFrames = index;
        end
        
        function SetFirstFrame(app, index)
            app.firstFrame = index;
        end

        function SetLastFrame(app, index)
            app.lastFrame = index;
        end

        % Set limits of frame slider
        function SetSliderLimits(app, first, last)
            app.SliderCurrentFrame.Limits(1) = Clamp(first, 1, app.numFrames);
            app.SliderCurrentFrame.Limits(2) = Clamp(last, 1, app.numFrames);
        end

        function UpdateCrosshair(app)
            app.EditHorzTrackPosition.Value = round(mean([app.EditCropLeft.Value app.EditCropRight.Value]));
            app.EditVertTrackPosition.Value = round(mean([app.EditCropUpper.Value app.EditCropLower.Value]));          
        end

        function MakeGray(app, force)
            if (isempty(app.imageGray) || force) && ~isempty(app.imageRGB) 
                n = length(app.imageRGB);
                app.imageGray = cell(1, n);
                wb = waitbar(0, 'Grayscaling images');                
                for i = 1:n
                    app.imageGray{i} = im2gray(im2single(app.imageRGB{i}));
                    waitbar(i / n, wb);
                end
                close(wb);
            end
        end

        function MakeBinary(app, force)
            MakeGray(app, false);
            if (isempty(app.imageBin) || force) && ~isempty(app.imageGray)                
                n = length(app.imageGray);
                app.imageBin = cell(1, n);
                wb = waitbar(0, 'Binarizing images');                
                for i = 1:n
                    app.imageBin{i} = imbinarize(app.imageGray{i});
                    %app.imageBin{i} = imerode(app.imageBin{i}, strel('disk', erodeRadius));
                    %app.imageBin{i} = imdilate(app.imageBin{i}, strel('disk', dilateRadius));
                    waitbar(i / n, wb);
                end
                close(wb);
            end
        end
        
        % Srip frames before firstFrame and after lastFrame off
        function outages = StripImages(app, images, message)            
            if ~isempty(images)
                wb = waitbar(0, message);
                n = app.lastFrame - app.firstFrame + 1;
                outages = cell(1, n);
                i = 0;
                for j = app.firstFrame:app.lastFrame
                    i = i + 1;
                    outages{i} = images{j};
                    waitbar(i / n, wb);
                end
                close(wb);
            else
                outages = images;
            end
        end

        function ResetCropEdits(app)
            app.EditCropLeft.Value = 1;
            app.EditCropUpper.Value = 1;            
            app.EditCropRight.Value = app.width;
            app.EditCropLower.Value = app.height;            
        end

        % Set app.cropRect to specified margins or fullsize
        function CropRect(app, fullSize)
            if fullSize  % no cropping
                app.cropRect = [1, 1, app.width, app.height];
            else  % crop to specified rect
                SetFrameSize(app, app.EditCropLower.Value - app.EditCropUpper.Value, ...
                    app.EditCropRight.Value - app.EditCropLeft.Value);
                app.cropRect = [app.EditCropLeft.Value, app.EditCropUpper.Value, ...
                    app.width - 1, app.height - 1];
            end
        end

        % Crop image struct if not empty
        function images = CropImages(app, images, message)
            if ~isempty(images)
                wb = waitbar(0, message);
                for i = 1:app.numFrames
                    images{i} = imcrop(images{i}, app.cropRect);
                    waitbar(i / app.numFrames, wb);
                end
                close(wb);
            end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ButtonImportVideo
        function ButtonImportVideoPushed(app, event)
           try     
                DisableAllElements(app);
    
                app.videoFileName = uigetfile({'*.MOV';'*.mov';'*.AVI';'*.avi'}, 'File Selector', app.inPath);
                %app.videoFileName = "150x25x1,1_RQC203.MOV";
    
                disp("Open file: " + app.videoFileName)
    
                stream = VideoReader(fullfile(app.inPath, app.videoFileName));
                SetFrameSize(app, stream.Height, stream.Width)
                
                app.numFrames = floor(stream.NumFrames * app.EditTotalFramesRatio.Value);
                
                CropRect(app, ~app.CheckBoxPreCrop.Value);  % crop (false) or fullsize (true)
                app.imageRGB = cell(1, stream.NumFrames);
                
                % Read movie frames into image
                wb = waitbar(0, 'Importing video');
                for i = 1:app.numFrames
                    stream.CurrentTime = (i - 1) / stream.FrameRate;
                    if app.CheckBoxPreCrop.Value
                        app.imageRGB{i} = imcrop(readFrame(stream), app.cropRect);
                    else
                        app.imageRGB{i} = readFrame(stream);
                    end
                    waitbar(i / app.numFrames, wb);
                end
            
                if app.CheckBoxPreCrop.Value
                    ResetCropEdits(app);
                end
                UpdateCrosshair(app);
    
                SetNumFrames(app, i);
                SetFirstFrame(app, 1);
                SetLastFrame(app, app.numFrames);
                SetSliderLimits(app, 1, app.numFrames);
                SetReferenceLengths(app);
    
                close(wb)

                EnableAfterImport(app);
                Refresh(app);

            catch exception
                disp(exception.identifier);
                disp(exception.message);
                EnableAfterImport(app);
            end

            
        end

        % Value changed function: EditCurrentFrame
        function EditCurrentFrameValueChanged(app, event)
            GotoFrame(app, app.EditCurrentFrame.Value);            
        end

        % Button pushed function: ButtonFrameForward
        function ButtonFrameForwardPushed(app, event)
            if app.currentFrame < app.numFrames
                GotoFrame(app, app.currentFrame + app.frameStep);
            else
                GotoFrame(app, 1);
            end
        end

        % Button pushed function: ButtonFrameBackward
        function ButtonFrameBackwardPushed(app, event)
            if app.currentFrame > 1
                GotoFrame(app, app.currentFrame - app.frameStep);
            else
                GotoFrame(app, app.numFrames);
            end
        end

        % Value changed function: EditFrameStep
        function EditFrameStepValueChanged(app, event)
            app.frameStep = app.EditFrameStep.Value;            
        end

        % Button pushed function: ButtonPlay
        function ButtonPlayPushed(app, event)
            app.playing = true;
            while app.playing
                if app.currentFrame >= app.lastFrame  % after limits
                    GotoFrame(app, app.firstFrame);  % jump to start
                elseif app.currentFrame < app.firstFrame  % before limits
                    GotoFrame(app, app.firstFrame);  % jump to start
                else  % within limits
                    GotoFrame(app, app.currentFrame + app.frameStep);  % next frame
                end
            end
        end

        % Button pushed function: ButtonPause
        function ButtonPausePushed(app, event)
            app.playing = false;
        end

        % Value changed function: EditCropLeft
        function EditCropLeftValueChanged(app, event)
            UpdateCrosshair(app);
            Refresh(app);
        end

        % Value changed function: EditCropUpper
        function EditCropUpperValueChanged(app, event)
            UpdateCrosshair(app);
            Refresh(app);
        end

        % Value changed function: EditCropRight
        function EditCropRightValueChanged(app, event)
            UpdateCrosshair(app);
            Refresh(app);
        end

        % Value changed function: EditCropLower
        function EditCropLowerValueChanged(app, event)
            UpdateCrosshair(app);
            Refresh(app);
        end

        % Value changing function: SliderCurrentFrame
        function SliderCurrentFrameValueChanging(app, event)
            app.EditCurrentFrame.Value = event.Value;
            EditCurrentFrameValueChanged(app, event);
        end

        % Button pushed function: ButtonSetFirst
        function ButtonSetFirstPushed(app, event)
            SetFirstFrame(app, app.currentFrame);
        end

        % Button pushed function: ButtonSetLast
        function ButtonSetLastPushed(app, event)
            SetLastFrame(app, app.currentFrame);
        end

        % Value changed function: EditVertTrackPosition
        function EditVertTrackPositionValueChanged(app, event)
            Refresh(app);
        end

        % Value changed function: EditHorzTrackPosition
        function EditHorzTrackPositionValueChanged(app, event)
            Refresh(app);
        end

        % Selection changed function: RadioGroupDisplayMode
        function RadioGroupDisplayModeSelectionChanged(app, event)
            %selectedButton = app.RadioGroupDisplayMode.SelectedObject;
            if app.RadioGray.Value
                MakeGray(app, false);
            elseif app.RadioBinary.Value
                MakeBinary(app, false);
            end
            Refresh(app);
        end

        % Value changed function: EditReferencePixels
        function EditReferencePixelsValueChanged(app, event)
            %MakeBinary(app, true);
            %Refresh(app);
        end

        % Button pushed function: ButtonCrop
        function ButtonCropPushed(app, event)
            CropRect(app, false);
            app.imageRGB = CropImages(app, app.imageRGB, 'Cropping Color Images');
            app.imageGray = CropImages(app, app.imageGray, 'Cropping Grayscale Images');
            app.imageBin = CropImages(app, app.imageBin, 'Cropping Binarized Images');
            ResetCropEdits(app);
            UpdateCrosshair(app);
            Refresh(app);
        end

        % Button pushed function: ButtonStrip
        function ButtonStripPushed(app, event)
            app.imageRGB = StripImages(app, app.imageRGB, 'Stripping Color Images');
            app.imageGray = StripImages(app, app.imageGray, 'Stripping Grayscale Images');
            app.imageBin = StripImages(app, app.imageBin, 'Stripping Binarized Images');
            SetNumFrames(app, length(app.imageRGB));
            SetFirstFrame(app, 1);
            SetLastFrame(app, app.numFrames);
            SetSliderLimits(app, 1, app.numFrames);
            GotoFrame(app, 1);
        end

        % Button pushed function: ButtonTrackFilaments
        function ButtonTrackFilamentsPushed(app, event)

            MakeBinary(app, true);
            radiusExpect = mm2px(app, [0.3 0.7] * app.EditFilamentDiameter.Value, true);
            radiusExpect(1) = Clamp(radiusExpect(1), 6, Inf);  % Algorithm accuracy is limited for radius values <= 5.
            

            wb = waitbar(0, "Tracking filaments");
            
            %yRefPos = round(app.EditVertTrackPosition.Value - app.EditTrackWindowHeight.Value / 2);
           
            sensitivity = 0.95;
            for i = 1:app.numFrames
                app.houghCenters{i} = imfindcircles(app.imageBin{i}, ...
                    radiusExpect, 'Sensitivity', sensitivity, 'ObjectPolarity', 'bright', 'Method', 'TwoStage');
                waitbar(i / app.numFrames, wb);
            end
            close(wb);
            if app.EditTotalFramesRatio.Value == 1
                app.ButtonExportXY.Enable = "on";
            end
            Refresh(app);
        end

        % Button pushed function: ButtonReference
        function ButtonReferencePushed(app, event)
            app.EditReferencePixels.Value = app.EditCropRight.Value - app.EditCropLeft.Value;
            SetReferenceLengths(app);
        end

        % Button pushed function: ButtonExportXY
        function ButtonExportXYPushed(app, event)
            clc

            % vertical centroid position change per frame [px]            
            %vstep = tan(2 * pi / 2613) * app.EditBrushDiameter.Value / 2;  % vertical frame shift [mm]            
            vstep = tan(2 * pi / app.numFrames) * app.EditBrushDiameter.Value / 2;  % vertical frame shift [mm]
            halfHeight = round(app.height / 2);
            outerToBody = app.EditBrushBodyDiameter.Value / app.EditBrushDiameter.Value;
            
            wb = waitbar(0, "Matching and exporting");

            filmWidth = app.width;
            filmHeight = mm2px(app, app.numFrames * vstep, true) + halfHeight;
            film = uint8(zeros(filmHeight, filmWidth));
            
            for f = 1:app.numFrames
                centers = app.houghCenters{f};
                vshift = mm2px(app, (f-1) * vstep, false);  % vertical frame shift since first frame [px]
                centers(:,1) = Clamp(round(centers(:,1)), 1, filmWidth);
                centers(:,2) = Clamp(round(app.height - centers(:,2) + vshift - halfHeight), 1, filmHeight);

                for c = 1:size(centers, 1)
                    if centers(c,2) > 0 && centers(c,2) < filmHeight
                        film(filmHeight-centers(c,2),centers(c,1)) = film(filmHeight-centers(c,2), centers(c,1)) + 1;
                    end
                end
               
                waitbar(f / app.numFrames, wb);
            end
            film(film > 0) = 255;
            film = imdilate(film, strel('disk',4));
            %film = imerode(film, strel('disk',2));
            props = regionprops(imbinarize(film),'centroid');
            centroids = vertcat(props.Centroid);
            %centroids(:,1) = Clamp(centroids(:,1), 1, filmWidth);
            %centroids(:,2) = Clamp(centroids(:,2), 1, filmHeight);

            film = insertMarker(film, centroids, '+', 'Color', 'red');

            [~, fileName, ~] = fileparts(app.videoFileName); 
            outFile = fullfile(app.outPath, sprintf("XY_%s_N%d", fileName, size(centroids, 1)));
            imwrite(film, outFile + ".png")
            fileID = fopen(outFile + ".txt", 'w');
            
            for i = 1:size(centroids,1)
                x = px2mm(app, centroids(i,1));
                y = px2mm(app, centroids(i,2)) * outerToBody;
                fprintf(fileID,'%.3f,%.3f\n',[y x]);
            end
            fclose(fileID);
            close(wb);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            colormap(app.UIAxes, 'gray')
            app.ButtonImportVideo = uibutton(app.UIFigure, 'push');
            app.ButtonImportVideo.ButtonPushedFcn = createCallbackFcn(app, @ButtonImportVideoPushed, true);
            app.ButtonImportVideo.Text = 'Import Video';
            app.CheckBoxPreCrop = uicheckbox(app.UIFigure);
            app.CheckBoxPreCrop.Text = 'Crop During Import';
            app.CheckBoxPreCrop.Value = true;
            app.EditTotalFramesRatio = uieditfield(app.UIFigure, 'numeric');
            app.EditTotalFramesRatio.Limits = [0.005 1];
            app.EditTotalFramesRatio.Tooltip = {'0.01: import only first percent of frames'; '1.0: import all frames'};
            app.EditTotalFramesRatio.Value = 0.1;
            app.SliderCurrentFrame = uislider(app.UIFigure);
            app.SliderCurrentFrame.MajorTicks = [];
            app.SliderCurrentFrame.MajorTickLabels = {''};
            app.SliderCurrentFrame.ValueChangingFcn = createCallbackFcn(app, @SliderCurrentFrameValueChanging, true);
            app.SliderCurrentFrame.MinorTicks = [];
            app.SliderCurrentFrame.Enable = 'off';
            app.ButtonFrameForward = uibutton(app.UIFigure, 'push');
            app.ButtonFrameForward.ButtonPushedFcn = createCallbackFcn(app, @ButtonFrameForwardPushed, true);
            app.ButtonFrameForward.Enable = 'off';
            app.ButtonFrameForward.Text = '+';
            app.ButtonFrameBackward = uibutton(app.UIFigure, 'push');
            app.ButtonFrameBackward.ButtonPushedFcn = createCallbackFcn(app, @ButtonFrameBackwardPushed, true);
            app.ButtonFrameBackward.Enable = 'off';
            app.ButtonFrameBackward.Text = '−';
            app.GotoLabel = uilabel(app.UIFigure);
            app.GotoLabel.Text = {'Current'; 'Frame'};
            app.EditCurrentFrame = uieditfield(app.UIFigure, 'numeric');
            app.EditCurrentFrame.Limits = [1 Inf];
            app.EditCurrentFrame.RoundFractionalValues = 'on';
            app.EditCurrentFrame.ValueChangedFcn = createCallbackFcn(app, @EditCurrentFrameValueChanged, true);
            app.EditCurrentFrame.Enable = 'off';
            app.EditCurrentFrame.Value = 1;
            app.ButtonPlay = uibutton(app.UIFigure, 'push');
            app.ButtonPlay.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlayPushed, true);
            app.ButtonPlay.Enable = 'off';
            app.ButtonPlay.Text = 'Play';
            app.ButtonPause = uibutton(app.UIFigure, 'push');
            app.ButtonPause.ButtonPushedFcn = createCallbackFcn(app, @ButtonPausePushed, true);
            app.ButtonPause.Enable = 'off';
            app.ButtonPause.Text = 'Pause';
            app.ButtonSetFirst = uibutton(app.UIFigure, 'push');
            app.ButtonSetFirst.ButtonPushedFcn = createCallbackFcn(app, @ButtonSetFirstPushed, true);
            app.ButtonSetFirst.Enable = 'off';
            app.ButtonSetFirst.Text = 'Set First Frame';
            app.ButtonSetLast = uibutton(app.UIFigure, 'push');
            app.ButtonSetLast.ButtonPushedFcn = createCallbackFcn(app, @ButtonSetLastPushed, true);
            app.ButtonSetLast.Enable = 'off';
            app.ButtonSetLast.Text = 'Set Last Frame';
            app.GotoLabel_2 = uilabel(app.UIFigure);
            app.GotoLabel_2.Text = 'Left';
            app.EditCropLeft = uieditfield(app.UIFigure, 'numeric');
            app.EditCropLeft.Limits = [1 Inf];
            app.EditCropLeft.RoundFractionalValues = 'on';
            app.EditCropLeft.ValueChangedFcn = createCallbackFcn(app, @EditCropLeftValueChanged, true);
            app.EditCropLeft.Value = 410;
            app.GotoLabel_3 = uilabel(app.UIFigure);
            app.GotoLabel_3.Text = 'Right';
            app.EditCropRight = uieditfield(app.UIFigure, 'numeric');
            app.EditCropRight.Limits = [1 Inf];
            app.EditCropRight.RoundFractionalValues = 'on';
            app.EditCropRight.ValueChangedFcn = createCallbackFcn(app, @EditCropRightValueChanged, true);
            app.EditCropRight.Value = 710;
            app.GotoLabel_4 = uilabel(app.UIFigure);
            app.GotoLabel_4.Text = 'Upper';
            app.EditCropUpper = uieditfield(app.UIFigure, 'numeric');
            app.EditCropUpper.Limits = [1 Inf];
            app.EditCropUpper.RoundFractionalValues = 'on';
            app.EditCropUpper.ValueChangedFcn = createCallbackFcn(app, @EditCropUpperValueChanged, true);
            app.EditCropUpper.Value = 910;
            app.GotoLabel_5 = uilabel(app.UIFigure);
            app.GotoLabel_5.Text = 'Lower';
            app.EditCropLower = uieditfield(app.UIFigure, 'numeric');
            app.EditCropLower.Limits = [1 Inf];
            app.EditCropLower.RoundFractionalValues = 'on';
            app.EditCropLower.ValueChangedFcn = createCallbackFcn(app, @EditCropLowerValueChanged, true);
            app.EditCropLower.Value = 1010;
            app.ButtonSave = uibutton(app.UIFigure, 'push');
            app.ButtonSave.Enable = 'off';
            app.ButtonSave.Text = 'Save Session';
            app.ButtonLoad = uibutton(app.UIFigure, 'push');
            app.ButtonLoad.Enable = 'off';
            app.ButtonLoad.Text = 'Load Session';
            app.GotoLabel_7 = uilabel(app.UIFigure);
            app.GotoLabel_7.Text = {'Reference'; 'Length [px]'};
            app.GotoLabel_8 = uilabel(app.UIFigure);
            app.GotoLabel_8.Text = {'Frame'; 'Step'};
            app.EditFrameStep = uieditfield(app.UIFigure, 'numeric');
            app.EditFrameStep.Limits = [1 Inf];
            app.EditFrameStep.RoundFractionalValues = 'on';
            app.EditFrameStep.ValueChangedFcn = createCallbackFcn(app, @EditFrameStepValueChanged, true);
            app.EditFrameStep.Enable = 'off';
            app.EditFrameStep.Value = 1;
            app.RadioGroupDisplayMode = uibuttongroup(app.UIFigure);
            app.RadioGroupDisplayMode.SelectionChangedFcn = createCallbackFcn(app, @RadioGroupDisplayModeSelectionChanged, true);
            app.RadioGroupDisplayMode.Enable = 'off';
            app.RadioGroupDisplayMode.Title = 'Display Mode';
            app.RadioColor = uiradiobutton(app.RadioGroupDisplayMode);
            app.RadioColor.Enable = 'off';
            app.RadioColor.Text = 'Color';
            app.RadioColor.Value = true;
            app.RadioGray = uiradiobutton(app.RadioGroupDisplayMode);
            app.RadioGray.Enable = 'off';
            app.RadioGray.Text = 'Grayscale';
            app.RadioBinary = uiradiobutton(app.RadioGroupDisplayMode);
            app.RadioBinary.Enable = 'off';
            app.RadioBinary.Text = 'Binary';
            app.GotoLabel_9 = uilabel(app.UIFigure);
            app.GotoLabel_9.Text = {'Outer Brush'; 'Diameter [mm]'};
            app.Status = uieditfield(app.UIFigure, 'text');
            app.Status.Editable = 'off';
            app.EditBrushDiameter = uieditfield(app.UIFigure, 'numeric');
            app.EditBrushDiameter.Value = 150;
            app.GotoLabel_17 = uilabel(app.UIFigure);
            app.GotoLabel_17.Text = {'Brush Body'; 'Diameter [mm]'};
            app.EditBrushBodyDiameter = uieditfield(app.UIFigure, 'numeric');
            app.EditBrushBodyDiameter.Value = 100;
            app.GotoLabel_10 = uilabel(app.UIFigure);
            app.GotoLabel_10.Text = {'Brush'; 'Width [mm]'};
            app.EditBrushWidth = uieditfield(app.UIFigure, 'numeric');
            app.EditBrushWidth.Value = 22;
            app.GotoLabel_11 = uilabel(app.UIFigure);
            app.GotoLabel_11.Text = {'Filament'; 'Diameter [mm]'};
            app.EditFilamentDiameter = uieditfield(app.UIFigure, 'numeric');
            app.EditFilamentDiameter.Value = 1.143;
            app.EditReferencePixels = uieditfield(app.UIFigure, 'numeric');
            app.EditReferencePixels.Limits = [1 Inf];
            app.EditReferencePixels.RoundFractionalValues = 'on';
            app.EditReferencePixels.ValueChangedFcn = createCallbackFcn(app, @EditReferencePixelsValueChanged, true);
            app.EditReferencePixels.Value = 515;
            app.GotoLabel_18 = uilabel(app.UIFigure);
            app.GotoLabel_18.Text = {'Reference'; 'Length [mm]'};
            app.EditReferenceMillimeters = uieditfield(app.UIFigure, 'numeric');
            app.EditReferenceMillimeters.Limits = [1 Inf];
            app.EditReferenceMillimeters.Value = 33.1;
            app.CheckBoxShowTracked = uicheckbox(app.UIFigure);
            app.CheckBoxShowTracked.Text = 'Show Tracked Filaments';
            app.CheckBoxShowTracked.Value = true;
            app.ButtonStrip = uibutton(app.UIFigure, 'push');
            app.ButtonStrip.ButtonPushedFcn = createCallbackFcn(app, @ButtonStripPushed, true);
            app.ButtonStrip.Enable = 'off';
            app.ButtonStrip.Text = 'Strip';
            app.ButtonCrop = uibutton(app.UIFigure, 'push');
            app.ButtonCrop.ButtonPushedFcn = createCallbackFcn(app, @ButtonCropPushed, true);
            app.ButtonCrop.Enable = 'off';
            app.ButtonCrop.Text = 'Crop';
            app.GotoLabel_14 = uilabel(app.UIFigure);
            app.GotoLabel_14.Text = {'Vertical Tracking'; 'Position [px]'};
            app.EditVertTrackPosition = uieditfield(app.UIFigure, 'numeric');
            app.EditVertTrackPosition.Limits = [1 Inf];
            app.EditVertTrackPosition.RoundFractionalValues = 'on';
            app.EditVertTrackPosition.ValueChangedFcn = createCallbackFcn(app, @EditVertTrackPositionValueChanged, true);
            app.EditVertTrackPosition.Enable = 'off';
            app.EditVertTrackPosition.Value = 1;
            app.GotoLabel_15 = uilabel(app.UIFigure);
            app.GotoLabel_15.Text = {'Horizontal Tracking'; 'Position [px]'};
            app.EditHorzTrackPosition = uieditfield(app.UIFigure, 'numeric');
            app.EditHorzTrackPosition.Limits = [1 Inf];
            app.EditHorzTrackPosition.RoundFractionalValues = 'on';
            app.EditHorzTrackPosition.ValueChangedFcn = createCallbackFcn(app, @EditHorzTrackPositionValueChanged, true);
            app.EditHorzTrackPosition.Enable = 'off';
            app.EditHorzTrackPosition.Value = 1;
            app.GotoLabel_16 = uilabel(app.UIFigure);
            app.GotoLabel_16.Text = {'Total Frames'; 'Ratio'};
            app.ButtonTrackFilaments = uibutton(app.UIFigure, 'push');
            app.ButtonTrackFilaments.ButtonPushedFcn = createCallbackFcn(app, @ButtonTrackFilamentsPushed, true);
            app.ButtonTrackFilaments.Text = 'Track Filaments';
            app.ButtonExportXY = uibutton(app.UIFigure, 'push');
            app.ButtonExportXY.ButtonPushedFcn = createCallbackFcn(app, @ButtonExportXYPushed, true);
            app.ButtonExportXY.Tooltip = {'Total Frames Ratio must be 1'};
            app.ButtonExportXY.Text = 'Export XY';
            app.ButtonReference = uibutton(app.UIFigure, 'push');
            app.ButtonReference.ButtonPushedFcn = createCallbackFcn(app, @ButtonReferencePushed, true);
            app.ButtonReference.Text = 'Reference';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end